classdef page3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Label_15          matlab.ui.control.Label
        Button_10         matlab.ui.control.Button
        Label_14          matlab.ui.control.Label
        EditField_sb      matlab.ui.control.NumericEditField
        Label_13          matlab.ui.control.Label
        EditField_vb      matlab.ui.control.NumericEditField
        Label_12          matlab.ui.control.Label
        EditField_mb      matlab.ui.control.NumericEditField
        Label_11          matlab.ui.control.Label
        Button_9          matlab.ui.control.Button
        Button_8          matlab.ui.control.Button
        Button_7          matlab.ui.control.Button
        Button_6          matlab.ui.control.Button
        Button_5          matlab.ui.control.Button
        Button_4          matlab.ui.control.Button
        Slider            matlab.ui.control.Slider
        EditField_cs      matlab.ui.control.NumericEditField
        EditField_9Label  matlab.ui.control.Label
        msLabel_2         matlab.ui.control.Label
        EditField_n       matlab.ui.control.NumericEditField
        Label_9           matlab.ui.control.Label
        EditField_sigma   matlab.ui.control.NumericEditField
        Label_8           matlab.ui.control.Label
        EditField_a       matlab.ui.control.NumericEditField
        Label_6           matlab.ui.control.Label
        EditField_rho     matlab.ui.control.NumericEditField
        Label_4           matlab.ui.control.Label
        EditField_T       matlab.ui.control.NumericEditField
        Label_3           matlab.ui.control.Label
        EditField_L       matlab.ui.control.NumericEditField
        Label_2           matlab.ui.control.Label
        kgmLabel_2        matlab.ui.control.Label
        NmLabel           matlab.ui.control.Label
        kgmLabel          matlab.ui.control.Label
        mLabel_2          matlab.ui.control.Label
        mLabel            matlab.ui.control.Label
        Button_3          matlab.ui.control.Button
        UIAxes2           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Value_Changed(app)
        end

        % Callback function
        function ButtonPushed(app, event)
            delete(app.UIFigure)
            page2
        end

        % Button pushed function: Button_3
        function Button_2Pushed(app, event)

            h=waitbar(0,'请稍等，计算耦合体系本征频率中');
            L=app.EditField_L.Value;
            a=app.EditField_a.Value;
            cs=app.EditField_T.Value;
            rho=app.EditField_rho.Value;
            a0=0;
            order_max=app.EditField_n.Value;
            sigma =app.EditField_sigma.Value;% 气球面密度
            ca = 340;% 空气中声速
            cs_ca=cs/ca;L_a=L/a;sigma_a_rho=sigma/a/rho;
            load('rootBessel.mat')
            load('rootBesselDiff.mat')
            for n=0:order_max
                waitbar(n/order_max,h)
                OmegaResonance(n,order_max, cs_ca,L_a, sigma_a_rho,rootBessel,rootBesselDiff);
            end
            delete(h);
            save('para.mat','L_a','sigma_a_rho','cs_ca','L','a','ca','order_max','sigma','cs','rho')

            fileList=dir('./OmegaRe/*.mat');

            Omega=zeros(length(fileList),order_max);
            for ni =1:length(fileList)
                load(strcat(fileList(ni).folder,'/', fileList(ni).name));
                while length(OmegaRe)<order_max
                    OmegaRe=[OmegaRe nan];
                end
                Omega(ni,:)=OmegaRe(1:order_max);
            end
            save('OmegaRe.mat',"Omega")

            fileList=dir('./Trans/*.mat');

            transMat=zeros(length(fileList),order_max,order_max);
            for ni =1:length(fileList)
                load(strcat(fileList(ni).folder,'/', fileList(ni).name));
                transMat(ni,:,:)=trans(1:order_max,1:order_max);
            end
            save('transMat.mat',"transMat")
            %%
            load(".\OmegaRe.mat"); % 计算得到的OmegaRe
            load('.\transMat.mat'); % a^k_ni
            load('.\rootBessel.mat');
            load('.\rootBesselDiff.mat');
            load('.\para.mat')
            Omega=Omega(1:order_max+1,1:order_max)/2;% 一致化
            save('Omega.mat','Omega');
            r_num=100;
            theta_num=100;% 切分的数目
            z_num=100;
            r=linspace(0,1,r_num)';% 注意有转置，列矢*行矢比较方便 r(0)对应中间
            theta=linspace(0,2*pi,theta_num);
            z(1,1,:)=linspace(0,L,z_num);
            % z0=zeros(r_num,theta_num);
            % z0(floor(r_num*a0/a)+1,1)=1; % 敲击位置，方程可以后续再改
            save('cut_num.mat','r_num','theta_num','z_num');

            %%
            % 锥形初始条件
            h0=0.003;

            ab=sqrt(r.^2+a0^2-2*r*a0*cos(theta));
            cosoba=(r.^2+ab.^2-a0^2)/2./r./ab;
            coscbo=-cosoba;
            cb=(2*coscbo.*r+sqrt((2*coscbo.*r).^2-4*(r.^2-1^2)))/2;
            z0=h0*cb./(cb+ab);
            z0(isnan(z0))=h0*1/(1+a0);
            z0=max(max(z0))-z0;
            z0=exp(-z0.^2);

            for nn=0:order_max
                mat_tmp=reshape(transMat(nn+1,1:order_max,1:order_max),order_max,order_max);
                for ii=1:order_max
                    [~,idx]=max(abs(mat_tmp(:,ii)));
                    mat_tmp(:,ii)=-mat_tmp(:,ii)*sign(mat_tmp(idx,ii));
                    % 最大的数字的符号为负
                end
                [i, j] = linear_sum_assignment(mat_tmp');% 匈牙利算法
                % 行i和列j对应，由于多了一个转置，实际上是列i和行j对应
                mat_tmp=-mat_tmp(i,:);
                transMat(nn+1,1:order_max,1:order_max)=mat_tmp;
            end
            warning('off')
            % surfShape(z0,r,theta);
            %%
            ds=pi*2*r/r_num/theta_num;
            ds=repmat(ds,1,theta_num);
            w_cm=zeros(order_max+1,order_max);
            shape_c=cell(order_max+1,order_max);
            for nn=0:order_max
                for mm=1:order_max
                    if nn==0 && mm==1
                        z1=sqrt(2)*ones(size(r))*cos(nn*theta)/sqrt(pi)/sqrt(2);
                    elseif nn==0
                        z1=sqrt(2)*rootBesselDiff(nn+1,mm)*besselj(nn,rootBesselDiff(nn+1,mm)*r)/...
                            besselj(nn,rootBesselDiff(nn+1,mm))/sqrt(rootBesselDiff(nn+1,mm)^2-nn^2)*...
                            cos(nn*theta)/sqrt(pi)/sqrt(2);
                    else%mm 需要+1
                        z1=sqrt(2)*rootBesselDiff(nn+1,mm+1)*besselj(nn,rootBesselDiff(nn+1,mm+1)*r)/...
                            besselj(nn,rootBesselDiff(nn+1,mm+1))/sqrt(rootBesselDiff(nn+1,mm+1)^2-nn^2)*...
                            cos(nn*theta)/sqrt(pi);
                    end
                    shape_c{nn+1,mm}=z1;
                    w_cm(nn+1,mm)=sum(sum(z0.*z1.*ds));
                end
            end

            shape_m=cell(order_max,order_max);
            for nn=0:order_max
                for mm=1:order_max
                    z1=sqrt(2)*besselj(nn,rootBessel(nn+1,mm)*r)/...
                        besselj(nn+1,rootBessel(nn+1,mm))*...
                        cos(nn*theta)/sqrt(pi)/sqrt(1+(nn==0));
                    shape_m{nn+1,mm}=z1;
                end
            end

            %膜的初态按照腔的本征态展开
            %%
            % 角标应为n m k
            w_cn=zeros(size(w_cm));
            for nn=0:order_max
                w_cn(nn+1,:)=w_cm(nn+1,1:order_max)*reshape(transMat(nn+1,1:order_max,1:order_max),order_max,order_max);
            end
            save('wcn.mat',"w_cn")
            save('shape.mat','shape_m','shape_c','r','theta');
            delete(app.UIFigure)
            page4_0

        end

        % Callback function: EditField_L, EditField_a, UIAxes2
        function Value_Changed(app, event)
            L=app.EditField_L.Value;
            a=app.EditField_a.Value;
            sigma=app.EditField_sigma.Value;
            rho=app.EditField_rho.Value;
            cs=app.EditField_cs.Value;
            ca=340;
            app.EditField_mb.Value=sigma/(rho*a);
            app.EditField_vb.Value=cs/ca;
            app.EditField_sb.Value=L/a;
            %a0=app.EditField_a0.Value;

            hh=0:0.001:L;
            th=0:pi/200:2*pi;
            xx=zeros(length(hh),length(th));
            yy=zeros(length(hh),length(th));
            zz=zeros(length(hh),length(th));
            for hi =1:length(hh)
                xx(hi,:)=a.*cos(th);
                zz(hi,:)=hh(hi);
                yy(hi,:)=a.*sin(th);
            end
            drum_draw=surf(app.UIAxes2,xx,yy,zz);
            set(drum_draw,'FaceColor',[0.3 0.3 0.3], 'FaceAlpha',1,'edgecolor','none');
            light(app.UIAxes2,'Position',[-3 0 2],'Style','local');
            light(app.UIAxes2,'Position',[-3 0 2],'Style','local');
            light(app.UIAxes2,'Position',[-3 0 2],'Style','local');
            app.UIAxes2.NextPlot='add';
            view(app.UIAxes2,[-5,-5,5]);
            if a>L
                axis(app.UIAxes2,[-a*1.1,a*1.1,-a*1.1,a*1.1,-a*1.1,a*1.1]);
            else
                axis(app.UIAxes2,[-L*1.1,L*1.1,-L*1.1,L*1.1,-L*1.1,L*1.1]);
            end
            xx=a.*cos(th);
            yy=a.*sin(th);
            zz=zeros(size(xx))+L;
            fill3(app.UIAxes2,xx,yy,zz,[1,0.9,0.3]-([1,0.9,0.3]-[1,1,0.87]).*(cs/100));
            app.UIAxes2.NextPlot='replacechildren';

        end

        % Value changed function: EditField_sigma
        function EditField_7ValueChanged(app, event)
            sigma=app.EditField_sigma.Value;
            T=app.EditField_T.Value;
            cs=sqrt(T/sigma);
            app.EditField_cs.Value=cs;
            Value_Changed(app, event)

        end

        % Value changed function: EditField_T
        function EditField_TValueChanged(app, event)
            T_input=app.EditField_T.Value;
            app.Slider.Value=T_input;
            cs=sqrt(T_input/app.EditField_sigma.Value);
            app.EditField_cs.Value=cs;
            Value_Changed(app, event)

        end

        % Callback function: Slider, Slider
        function SliderValueChanged(app, event)
            T_slider=app.Slider.Value;
            app.EditField_T.Value=T_slider;
            cs=sqrt(T_slider/app.EditField_sigma.Value);
            app.EditField_cs.Value=cs;
            Value_Changed(app, event)

        end

        % Value changed function: EditField_cs
        function EditField_csValueChanged(app, event)
            cs=app.EditField_cs.Value;
            T=cs*cs*app.EditField_sigma.Value;
            app.Slider.Value=T;
            app.EditField_T.Value=T;
            Value_Changed(app, event)

        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            page4_0
            delete(app.UIFigure)
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            page4
            delete(app.UIFigure)
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            page5_0
            delete(app.UIFigure)
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            page5_1
            delete(app.UIFigure)
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            page4_1
            delete(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XLim = [-0.5 0.5];
            app.UIAxes2.XTick = [-0.5 -0.25 0 0.25 0.5];
            app.UIAxes2.XTickLabel = {'-0.5'; '-0.25'; '0'; '0.25'; '0.5'};
            app.UIAxes2.YTick = [0 1 2];
            app.UIAxes2.ZTick = [0 1 2];
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @Value_Changed, true);
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_3.Text = '开始计算';
            app.mLabel = uilabel(app.UIFigure);
            app.mLabel.Text = 'm';
            app.mLabel_2 = uilabel(app.UIFigure);
            app.mLabel_2.Text = 'm';
            app.kgmLabel = uilabel(app.UIFigure);
            app.kgmLabel.Text = 'kg/m²';
            app.NmLabel = uilabel(app.UIFigure);
            app.NmLabel.Text = 'N/m';
            app.kgmLabel_2 = uilabel(app.UIFigure);
            app.kgmLabel_2.Text = 'kg/m³';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '鼓的高度';
            app.EditField_L = uieditfield(app.UIFigure, 'numeric');
            app.EditField_L.ValueDisplayFormat = '%.2f';
            app.EditField_L.ValueChangedFcn = createCallbackFcn(app, @Value_Changed, true);
            app.EditField_L.Value = 0.1;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '膜上张力';
            app.EditField_T = uieditfield(app.UIFigure, 'numeric');
            app.EditField_T.ValueChangedFcn = createCallbackFcn(app, @EditField_TValueChanged, true);
            app.EditField_T.Value = 90;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '空气密度';
            app.EditField_rho = uieditfield(app.UIFigure, 'numeric');
            app.EditField_rho.Value = 1.184;
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '鼓的半径';
            app.EditField_a = uieditfield(app.UIFigure, 'numeric');
            app.EditField_a.ValueDisplayFormat = '%.2f';
            app.EditField_a.ValueChangedFcn = createCallbackFcn(app, @Value_Changed, true);
            app.EditField_a.Value = 0.05;
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '膜面密度';
            app.EditField_sigma = uieditfield(app.UIFigure, 'numeric');
            app.EditField_sigma.ValueChangedFcn = createCallbackFcn(app, @EditField_7ValueChanged, true);
            app.EditField_sigma.Value = 0.1;
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '计算阶数';
            app.EditField_n = uieditfield(app.UIFigure, 'numeric');
            app.EditField_n.Limits = [0 50];
            app.EditField_n.ValueDisplayFormat = '%.0f';
            app.EditField_n.Value = 5;
            app.msLabel_2 = uilabel(app.UIFigure);
            app.msLabel_2.Text = 'm/s';
            app.EditField_9Label = uilabel(app.UIFigure);
            app.EditField_9Label.Text = '膜上波速';
            app.EditField_cs = uieditfield(app.UIFigure, 'numeric');
            app.EditField_cs.ValueChangedFcn = createCallbackFcn(app, @EditField_csValueChanged, true);
            app.EditField_cs.Value = 30;
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 1000];
            app.Slider.MajorTicks = [0 500 1000];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 90;
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '不同模式下的振动';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '给定初始条件的振动';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '膜上某点的振动';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.Text = '参数设置';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.Text = '数据导出';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '腔内某点的振动';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '质量比 𝜎/(𝜌𝑎) ';
            app.EditField_mb = uieditfield(app.UIFigure, 'numeric');
            app.EditField_mb.ValueDisplayFormat = '%.3f';
            app.EditField_mb.Editable = 'off';
            app.EditField_mb.Value = 1.689;
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '波速比 cₛ/cₐ    =';
            app.EditField_vb = uieditfield(app.UIFigure, 'numeric');
            app.EditField_vb.ValueDisplayFormat = '%.3f';
            app.EditField_vb.Editable = 'off';
            app.EditField_vb.Value = 0.088;
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '高径比 𝐿/𝑎      =';
            app.EditField_sb = uieditfield(app.UIFigure, 'numeric');
            app.EditField_sb.ValueDisplayFormat = '%.3f';
            app.EditField_sb.Editable = 'off';
            app.EditField_sb.Value = 2;
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '无量纲数：';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '频谱与声音';
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Text = '=';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = page3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end