classdef page4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button_7  matlab.ui.control.Button
        Button_6  matlab.ui.control.Button
        Button_5  matlab.ui.control.Button
        Button_4  matlab.ui.control.Button
        Button_3  matlab.ui.control.Button
        Button_2  matlab.ui.control.Button
        Button    matlab.ui.control.Button
        Slider_2  matlab.ui.control.Slider
        Label_2   matlab.ui.control.Label
        Slider    matlab.ui.control.Slider
        Label     matlab.ui.control.Label
        UIAxes    matlab.ui.control.UIAxes
        UIAxes2   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ButtonPushed(app, event)
            delete(app.UIFigure)
            page3
        end

        % Callback function
        function Button_3Pushed(app, event)

%             %%只有膜
%             load('.\rootBessel.mat');
%             load('.\para.mat');
%             load('.\cut_num.mat');
%             %order_max=10;% 需要修改
%             %a0=0.5; %敲击位置，(theta=0)
%             %cs=20;
%             a=1;L=1; %归一后
%             %ca=340;
%             Omega_mem=rootBessel*cs/a;
%             Omega_mem=Omega_mem(1:order_max+1,1:order_max)/2;% 一致化
%             %%
% %             r_num=100;
% %             theta_num=100;% 切分的数目
% %             z_num=100;
%             r=linspace(0,1,r_num)';% 注意有转置，列矢*行矢比较方便 r(0)对应中间
%             theta=linspace(0,2*pi,theta_num);
%             z(1,1,:)=linspace(0,L,z_num);
%             % z0=zeros(r_num,theta_num);
%             % z0(floor(r_num*a0/a)+1,1)=1; % 敲击位置，方程可以后续再改
%             %%
%             % 锥形初始条件
%             h0=1;
% 
%             ab=sqrt(r.^2+a0^2-2*r*a0*cos(theta));
%             cosoba=(r.^2+ab.^2-a0^2)/2./r./ab;
%             coscbo=-cosoba;
%             cb=(2*coscbo.*r+sqrt((2*coscbo.*r).^2-4*(r.^2-1^2)))/2;
%             z0=h0*cb./(cb+ab);
%             z0(isnan(z0))=h0*1/(1+a0);
%             %%
%             w_nm= zeros(size(Omega_mem));% 膜在膜的本征态上的展开系数的矩阵
%             shape_m=cell(order_max+1,order_max);% 记录膜在腔的本征态下的形状的cell
%             % r1=r-a/2/r_num;r2=r+a/2/r_num;
%             % ds=pi*(r2.^2-r1.^2)/theta_num;
%             ds=pi*2*r/r_num/theta_num;% 和上面两行等价
%             ds=repmat(ds,1,theta_num);
%             w_mm=zeros(order_max+1,order_max);
%             for nn=0:order_max
%                 for mm=1:order_max
%                     z1=sqrt(2)*besselj(nn,rootBessel(nn+1,mm)*r)/...
%                         besselj(nn+1,rootBessel(nn+1,mm))*...
%                         cos(nn*theta)/sqrt(pi)/sqrt(2);
%                     % 先是r,后是theta
%                     shape_m{nn+1,mm}=z1;
%                     w_mm(nn+1,mm)=sum(sum(z0.*z1.*ds));% 注释本行
%                 end
%             end
%             %频率为Omega_mem
% 
%             %% 画频谱 需要的话取消注释就行
%             fn=10000; % 横轴划分的个数
%             omega_limit=10;
%             x=linspace(0,omega_limit*ca/a/2/pi,fn);
%             y=zeros(1,fn);
%             for nn=0:order_max
%                 for mm=1:order_max
%                     y=y+abs(w_mm(nn+1,mm)*Omega_mem(nn+1,mm))./(1+(x-Omega_mem(nn+1,mm)/2/pi).^2/10);
%                 end
%             end
%             y=y/sum(y.^2);
%             plot(app.UIAxes,x,y,'Color',[0.4 0.3 0.8],'LineWidth',1)
%             xlim(app.UIAxes,[0 2e3])
%             title(app.UIAxes,'频谱','Color',[0 0 0])
%             xlabel(app.UIAxes,'频率(Hz)');
%             ylabel(app.UIAxes,'响度(A.U.)');
% 
%             %%
%             ii=1;
%             mkdir('./pic')
%             for t=0:1/min(min(Omega_mem))/2000:1/min(min(Omega_mem))
%                 shape=zeros(r_num,theta_num);
%                 for nn=0:order_max
%                     for mm=1:order_max
%                         shape=shape+shape_m{nn+1,mm}*w_mm(nn+1,mm)*cos(Omega_mem(nn+1,mm)*cs/a*t);
%                     end
%                 end
%                 h_mem=surfShape(app.UIAxes2,shape,r,theta);
%                 set(h_mem,'edgecolor','none');
%                 load('s_w.mat');
%                 load('s_h.mat');
%                 view(app.UIAxes2,see_where,see_high);  % 设置视点位置
%                 axis(app.UIAxes2,[-1,1,-1,1,-1.5*h0,1.5*h0]); % 显示不全调节最后两个参数
%                 title(app.UIAxes2,'膜的自由振动')
%                 pause(0.1)
%             end
%             %     saveas(gcf,['./pic/' num2str(ii) '.jpg'])
%             disp(t)
%             ii=ii+1;
%             pause(0.1)

        end

        % Callback function
        function Button_2Pushed(app, event)
        delete(app.UIFigure)
        page5
        end

        % Callback function
        function Button_4Pushed(app, event)
%%
%% 只有腔
% load('.\rootBessel.mat');
% load('.\para.mat');
% load('.\rootBesselDiff.mat');
% order_max=5;% 需要修改!!!!这个数字太大了电脑的内存不够用
% %a0=0.5; %敲击位置，(theta=0)
% %ca=345;a=1;L=1;
% Omega=rootBesselDiff*ca/a;
% Omega=Omega(1:order_max+1,1:order_max)/2;% 一致化
% %%
% r_num=100;
% theta_num=100;% 切分的数目
% z_num=100;
% r=linspace(0,1,r_num)';% 注意有转置，列矢*行矢比较方便 r(0)对应中间
% theta=linspace(0,2*pi,theta_num);
% z(1,1,:)=linspace(0,L,z_num);
% % z0=zeros(r_num,theta_num);
% % z0(floor(r_num*a0/a)+1,1)=1; % 敲击位置，方程可以后续再改
% %%
% % 锥形初始条件
% h0=1;
% 
% ab=sqrt(r.^2+a0^2-2*r*a0*cos(theta));
% cosoba=(r.^2+ab.^2-a0^2)/2./r./ab;
% coscbo=-cosoba;
% cb=(2*coscbo.*r+sqrt((2*coscbo.*r).^2-4*(r.^2-1^2)))/2;
% z0=h0*cb./(cb+ab);
% z0(isnan(z0))=h0*1/(1+a0);
% z0=repmat(z0,1,1,z_num);
% %%
% w_nm= zeros(size(Omega));
% shape_c=cell(order_max+1,order_max,order_max);% 腔的本征态
% % r1=r-a/2/r_num;r2=r+a/2/r_num;
% % ds=pi*(r2.^2-r1.^2)/theta_num;
% dv=pi*2*r/r_num/theta_num*L/z_num;% 和上面两行等价
% dv=repmat(dv,1,theta_num);
% % w_cm=zeros(order_max+1,order_max);
% for kk=1:order_max% z方向量子数
%     for nn=0:order_max
%         for mm=1:order_max
%             if nn==0 && mm==1
%                 z1=repmat(sqrt(2)*ones(size(r))*cos(nn*theta),1,1,z_num).*...
%                     repmat(sin((kk-1/2)*pi*z/L),r_num,theta_num,1)/sqrt(pi)/sqrt(2);
%             elseif nn==0
%                 z1=repmat(sqrt(2)*rootBesselDiff(nn+1,mm)*besselj(nn,rootBesselDiff(nn+1,mm)*r)/...
%                     besselj(nn,rootBesselDiff(nn+1,mm))/sqrt(rootBesselDiff(nn+1,mm)^2-nn^2)*...
%                     cos(nn*theta),1,1,z_num).*...
%                     repmat(sin((kk-1/2)*pi*z/L),r_num,theta_num,1)/sqrt(pi)/sqrt(2);
%             else%mm 需要+1
%                 z1=repmat(sqrt(2)*rootBesselDiff(nn+1,mm+1)*besselj(nn,rootBesselDiff(nn+1,mm+1)*r)/...
%                     besselj(nn,rootBesselDiff(nn+1,mm+1))/sqrt(rootBesselDiff(nn+1,mm+1)^2-nn^2)*...
%                     cos(nn*theta),1,1,z_num).*...
%                     repmat(sin((kk-1/2)*pi*z/L),r_num,theta_num,1)/sqrt(pi);
%             end
%             % 先是r,后是theta
%             shape_c{nn+1,mm,kk}=z1;
%             w_cm(nn+1,mm)=sum(sum(sum(z0.*z1.*dv)));
%         end
%     end
% end
% 
% %频率为Omega,理论上z轴需要乘sin((k+1/2)z)
% %% 仍然是不要了的频谱
%             fn=10000; % 横轴划分的个数
%             omega_limit=10;
%             x=linspace(0,omega_limit*ca/a/2/pi,fn);
%             y=zeros(1,fn);
%             for nn=0:order_max
%                 for mm=1:order_max
%                     y=y+abs(w_cm(nn+1,mm)*Omega(nn+1,mm))./(1+(x-Omega(nn+1,mm)/2/pi).^2/10);
%                 end
%             end
%             y=y/max(y);
%             plot(app.UIAxes,x,y)
%             xlim(app.UIAxes,[0 2e3])
%             title(app.UIAxes,'频谱图')
% 
% %%
% % tmp=shape_c{2,2,1};
% % figure(1)
% % contourfShape(tmp,r,theta,z,0.5);
% %%
% tmp=shape_c{2,2,1};
% % figure(1)
% h_air=surfShape(app.UIAxes2,tmp(:,:,end),r,theta);
% set(h_air,'edgecolor','none');
% % figure(2)
% % h2=surfShape(app.UIAxes2,tmp(:,:,1),r,theta);
% % set(h2,'edgecolor','none');


        end

        % Button pushed function: Button_2
        function Button_5Pushed(app, event)
        load('.\para.mat');
        load('.\wcn.mat');
        load('.\Omega.mat');
        load('.\cut_num.mat');
        load('.\shapem.mat');
        fn=10000; % 横轴划分的个数
        omega_limit=10;ca=340;a=0.5;order_max=10;
        x=linspace(0,omega_limit*ca/a/2/pi,fn);
        y=zeros(1,fn);
        for nn=0:order_max
            for mm=1:order_max
                y=y+abs(w_cn(nn+1,mm)*Omega(nn+1,mm))./(1+(x-Omega(nn+1,mm)*ca/a/2/pi).^2/10);
            end
        end
        y=y/max(y);
        plot(app.UIAxes,x,y)
        xlim(app.UIAxes,[0 2e3])
        title(app.UIAxes,'频谱图')

        ii=0;
        for t=0:0.0001:0.02
            shape=zeros(r_num,theta_num);
            for nn=0:order_max
                for mm=1:order_max
                    shape=shape+shape_m{nn+1,mm}*w_cn(nn+1,mm)*cos(Omega(nn+1,mm)*ca/a*t);
                end
            end
            try
                h_all=surfShape(app.UIAxes2,shape,r,theta);
                set(h_all,'edgecolor','none')
                view(app.UIAxes2,-30,70);  % 设置视点位置
                axis(app.UIAxes2,[-1,1,-1,1,-3*h0,3*h0]); % 显示不全调节最后两个参数
                title(app.UIAxes2,t)
            catch
                break
            end
            %     saveas(gcf,['./pic/' num2str(ii) '.jpg'])
            %                 disp(t)
            ii=ii+1;
            pause(0.1)
        end
        end

        % Callback function
        function Button_6Pushed(app, event)

        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            see_where=app.Slider.Value;
           save('s_w.mat','see_where');    
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            see_high=app.Slider_2.Value;
            save('s_h.mat','see_high');   
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
        load('.\para.mat');
        load('.\wcn.mat');
        load('.\Omega.mat');
        load('.\cut_num.mat');
        load('.\shapem.mat');
        fn=10000; % 横轴划分的个数
        omega_limit=10;ca=340;a=0.5;order_max=10;
        x=linspace(0,omega_limit*ca/a/2/pi,fn);
        y=zeros(1,fn);
        for nn=0:order_max
            for mm=1:order_max
                y=y+abs(w_cn(nn+1,mm)*Omega(nn+1,mm))./(1+(x-Omega(nn+1,mm)*ca/a/2/pi).^2/10);
            end
        end
        y=y/max(y);
            AA=y;
            FF=x;
            fs=10000;
            NN=3000;
            yy=AA(1).*sin(2*pi.*FF(1).*(0:NN-1)./fs);
            for iii=2:length(y) 
            yy=yy+AA(iii).*sin(2*pi.*FF(iii).*(0:NN-1)./fs);
            end
            sound(yy,fs);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '频谱')
            xlabel(app.UIAxes, '频率(Hz)')
            ylabel(app.UIAxes, '响度(A.U.)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 500 1000 1500 2000];
            app.UIAxes.XTickLabel = {'0'; '500'; '1000'; '1500'; '2000'};
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '方位角';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [-90 90];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '仰角';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [-90 90];
            app.Slider_2.MajorTicks = [-90 -45 0 45 90];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Text = ' 不同模式下的振动';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_2.Text = '给定初始条件的振动';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.Text = ' 鼓上某点的振动';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.Text = ' 参数设置';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.Text = ' 数据导出';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.Text = ' 鼓上某点的振动';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '播放声音';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = page4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end