classdef page5_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Image                   matlab.ui.control.Image
        Button_7                matlab.ui.control.Button
        EditField_h_change      matlab.ui.control.NumericEditField
        Label_11                matlab.ui.control.Label
        Slider_3                matlab.ui.control.Slider
        EditField_theta_change  matlab.ui.control.NumericEditField
        Label_9                 matlab.ui.control.Label
        EditField_r_change      matlab.ui.control.NumericEditField
        Label_8                 matlab.ui.control.Label
        Slider_2                matlab.ui.control.Slider
        Slider                  matlab.ui.control.Slider
        Button_6                matlab.ui.control.Button
        Button_5                matlab.ui.control.Button
        Button_4                matlab.ui.control.Button
        Button_3                matlab.ui.control.Button
        Button_2                matlab.ui.control.Button
        Button                  matlab.ui.control.Button
        UIAxes2_2               matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            EditField_r_changeValueChanged(app)
        end

        % Value changed function: EditField_r_change
        function EditField_r_changeValueChanged(app, event)
            load('para.mat');
            load("OmegaRe.mat"); % 计算得到的OmegaRe
            load('transMat.mat'); % a^k_ni
            load('rootBessel.mat');
            load('rootBesselDiff.mat');
            load('shape.mat')
            load('wcn.mat')
            load('cut_num.mat')
            load('a0.mat');
            r0=app.EditField_r_change.Value*a;
            h00=app.EditField_h_change.Value*L;
            app.Slider.Value=double(app.EditField_r_change.Value);
            theta0=app.EditField_theta_change.Value;
            
            hh=0:0.001:L;
            th=0:pi/200:2*pi;
            xx=zeros(length(hh),length(th));
            yy=zeros(length(hh),length(th));
            zz=zeros(length(hh),length(th));
            for hi =1:length(hh)
                xx(hi,:)=a.*cos(th);
                zz(hi,:)=hh(hi);
                yy(hi,:)=a.*sin(th);
            end
            drum_draw=surf(app.UIAxes2,xx,yy,zz);
            set(drum_draw,'FaceColor',[0.3 0.3 0.3], 'FaceAlpha',0.7,'edgecolor','none');
            light(app.UIAxes2,'Position',[-3 0 2],'Style','local');
            light(app.UIAxes2,'Position',[-3 0 2],'Style','local');
            light(app.UIAxes2,'Position',[-3 0 2],'Style','local');
            app.UIAxes2.NextPlot='add';
            view(app.UIAxes2,[-1,-1,-1]);
            axis(app.UIAxes2,[-a*1.2,a*1.2,-a*1.2,a*1.2,0,L*1.2]);
            xx=a.*cos(th);
            yy=a.*sin(th);
            zz=zeros(size(xx))+L;
%             fill3(app.UIAxes2,xx,yy,zz,[1,0.9,0.3]-([1,0.9,0.3]-[1,1,0.87]).*(cs/100));
                            
            x0=r0*cos(theta0/180*pi);
            y0=r0*sin(theta0/180*pi);
            mem_draw=fill3(app.UIAxes2,xx,yy,zz,[1,0.95,0.8]);
            mem_draw.FaceAlpha=0.7;

            app.UIAxes2.NextPlot='add';
            plot3(app.UIAxes2,linspace(0,1.1*a,2),zeros(1,2),zeros(1,2)+L,'-','Color','y','LineWidth',3);
            plot3(app.UIAxes2,linspace(0,0.9*a,2),linspace(0,0.9*a,2),zeros(1,2)+L,'-','Color','y','LineWidth',3);
            plot3(app.UIAxes2,0.2*a*sin(linspace(pi/4,pi/2,30)),0.2*a*cos(linspace(pi/4,pi/2,30)),zeros(1,30)+L,'-','Color','y','LineWidth',3);
%             plot3(app.UIAxes2,zeros(1,2),zeros(1,2),linspace(0,-1.1*L,2)+L,'-','Color','y','LineWidth',3);
            plot3(app.UIAxes2,1.1*a,0,L,'>','Color','y','LineWidth',3);
            plot3(app.UIAxes2,linspace(0.85*a,0.9*a,2),linspace(0.8*a,0.9*a,2),zeros(1,2)+L,'-','Color','y','LineWidth',3);
            plot3(app.UIAxes2,linspace(0.8*a,0.9*a,2),linspace(0.85*a,0.9*a,2),zeros(1,2)+L,'-','Color','y','LineWidth',3);            
%              plot3(app.UIAxes2,0,0,-0.1*L,'v','Color','y','LineWidth',3);
            plot3(app.UIAxes2,0,0,L,'.','Color','k','MarkerSize',10);
            plot3(app.UIAxes2,a0*a,0,L,'.','Color','r','MarkerSize',15);
            plot3(app.UIAxes2,x0,y0,L,'.','Color','b','MarkerSize',15);

            %view(app.UIAxes2,see_where00,see_high00);  % 设置视点位置
            set(app.UIAxes2.XAxis,'visible','off');
            set(app.UIAxes2.YAxis,'visible','off');
            set(app.UIAxes2.ZAxis,'visible','off');
            view(app.UIAxes2,[0,-1,20]);
            app.UIAxes2.NextPlot='replacechildren';

                        hh=0:0.001:L;
            th=0:pi/200:2*pi;
            xx=zeros(length(hh),length(th));
            yy=zeros(length(hh),length(th));
            zz=zeros(length(hh),length(th));
            for hi =1:length(hh)
                xx(hi,:)=a.*cos(th);
                zz(hi,:)=hh(hi);
                yy(hi,:)=a.*sin(th);
            end
            drum_draw=surf(app.UIAxes2_2,xx,yy,zz);
            set(drum_draw,'FaceColor',[0.3 0.3 0.3], 'FaceAlpha',0.7,'edgecolor','none');
            light(app.UIAxes2_2,'Position',[1 0 0],'Style','local');
            %light(app.UIAxes2_2,'Position',[1 0 0],'Style','local');
            %light(app.UIAxes2_2,'Position',[1 0 0],'Style','local');
            app.UIAxes2_2.NextPlot='add';
            view(app.UIAxes2_2,[-1,-1,-1]);
            axis(app.UIAxes2_2,[-a*1.2,a*1.2,-a*1.2,a*1.2,0,L*1.1]);
            xx=a.*cos(th);
            yy=a.*sin(th);
            zz=zeros(size(xx))+L;
%             fill3(app.UIAxes2,xx,yy,zz,[1,0.9,0.3]-([1,0.9,0.3]-[1,1,0.87]).*(cs/100));
                            
            x0=r0*cos(theta0/180*pi);
            y0=r0*sin(theta0/180*pi);
            mem_draw=fill3(app.UIAxes2_2,xx,yy,zz,[1,0.95,0.8]);
            mem_draw.FaceAlpha=0.7;

            app.UIAxes2.NextPlot='add';
            plot3(app.UIAxes2_2,linspace(0,1.1*a,2),zeros(1,2),zeros(1,2)+L,'-','Color','y','LineWidth',3);
            plot3(app.UIAxes2_2,linspace(0,0.9*a,2),linspace(0,0.9*a,2),zeros(1,2)+L,'-','Color','y','LineWidth',3);
            plot3(app.UIAxes2_2,0.2*a*sin(linspace(pi/4,pi/2,30)),0.2*a*cos(linspace(pi/4,pi/2,30)),zeros(1,30)+L,'-','Color','y','LineWidth',3);
            plot3(app.UIAxes2_2,zeros(1,2),zeros(1,2),linspace(0,-1.1*L,2)+L,'-','Color','y','LineWidth',3);
            plot3(app.UIAxes2_2,1.1*a,0,L,'>','Color','y','LineWidth',3);
            plot3(app.UIAxes2_2,linspace(0.85*a,0.9*a,2),linspace(0.8*a,0.9*a,2),zeros(1,2)+L,'-','Color','y','LineWidth',3);
            plot3(app.UIAxes2_2,linspace(0.8*a,0.9*a,2),linspace(0.85*a,0.9*a,2),zeros(1,2)+L,'-','Color','y','LineWidth',3);            
            plot3(app.UIAxes2_2,0,0,0,'v','Color','y','LineWidth',3);
            plot3(app.UIAxes2_2,0,0,L,'.','Color','k','MarkerSize',10);
            plot3(app.UIAxes2_2,a0*a,0,L,'.','Color','r','MarkerSize',15);
            plot3(app.UIAxes2_2,x0,y0,L-h00,'.','Color',[0 1 1],'MarkerSize',40);

            %view(app.UIAxes2,see_where00,see_high00);  % 设置视点位置
            set(app.UIAxes2_2.XAxis,'visible','off');
            set(app.UIAxes2_2.YAxis,'visible','off');
            set(app.UIAxes2_2.ZAxis,'visible','off');
            view(app.UIAxes2_2,[0,-1,0]);
            app.UIAxes2_2.NextPlot='replacechildren';
            app.UIAxes2.NextPlot='replacechildren';


            z_1=[];
            k=1;
            r0=app.EditField_r_change.Value;
            theta0=app.EditField_theta_change.Value;
            % 画p-t
            delta_t=0.000025;
            for t=0:delta_t:0.1
                shape_1=zeros(r_num,theta_num);
                for nn=0:order_max
                    for mm=1:order_max
                        shape_1=shape_1+shape_c{nn+1,mm}*w_cn(nn+1,mm)*cos(Omega(nn+1,mm)*ca/a*t)*sin((k-1/2)*pi*h00);
                    end
                end
                z_tmp=shape_1(1+floor(r0*r_num),1+floor(theta0/180*floor(theta_num/2)));
                z_1=[z_1 z_tmp];
            end

            % 画面
            p_t_x=(0:(length(z_1)-1))/length(z_1)*0.1;
            plot(app.UIAxes,p_t_x,z_1,'Color','g')
            ylim(app.UIAxes,[-0.000005,0.000005])
            xlim(app.UIAxes,[0,max(p_t_x)*0.25])
            title(app.UIAxes,'耦合体系腔的振动','Color','w')
            app.UIAxes.NextPlot='replacechildren';
            save('p_t.mat','p_t_x','z_1');
          
        end

        % Callback function
        function Slider_4ValueChanged(app, event)
            see_where=app.Slider_4.Value;
            save('s_w_page5_1.mat','see_where');
            EditField_r_changeValueChanged(app, event)

        end

        % Callback function
        function Slider_5ValueChanged(app, event)
            see_high=app.Slider_5.Value;
            save('s_h.mat_page5_1','see_high');
            EditField_r_changeValueChanged(app, event)

        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            r0=floor(app.Slider.Value*100)/100;
            app.EditField_r_change.Value=r0;
            EditField_r_changeValueChanged(app, event)

        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            xita0=floor(app.Slider_2.Value*100)/100;
            app.EditField_theta_change.Value=xita0;
            EditField_r_changeValueChanged(app, event)

        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            h0=floor(app.Slider_3.Value*100)/100;
            app.EditField_h_change.Value=h0;
            EditField_r_changeValueChanged(app, event)

        end

        % Value changed function: EditField_theta_change
        function EditField_theta_changeValueChanged(app, event)
            xita0=app.EditField_theta_change.Value;
            app.Slider_2.Value=xita0;
            EditField_r_changeValueChanged(app, event)

        end

        % Value changed function: EditField_h_change
        function EditField_h_changeValueChanged(app, event)
            h00=app.EditField_h_change.Value;
            app.Slider_3.Value=h00;
            EditField_r_changeValueChanged(app, event)

        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            page3
            delete(app.UIFigure)
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            page4_0
            delete(app.UIFigure)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            page4
            delete(app.UIFigure)
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            page5_0
            delete(app.UIFigure)
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            page6
            delete(app.UIFigure)
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            page4_1
            delete(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '频谱')
            xlabel(app.UIAxes, '时间(s)')
            ylabel(app.UIAxes, '声压(Pa)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 0.015];
            app.UIAxes.YLim = [-0.008 0.008];
            app.UIAxes.ZTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ZTick = [];
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.YTick = [];
            app.UIAxes2_2.ZTick = [];
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '不同模式下的振动';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '给定初始条件的振动';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '膜上某点的振动';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '参数设置';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '数据导出';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.Text = '腔内某点的振动';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 1];
            app.Slider.MajorTicks = [0 0.5 1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [0 180];
            app.Slider_2.MajorTicks = [0 90 180];
            app.Slider_2.MajorTickLabels = {'0', '90', '180'};
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = ' r/a';
            app.EditField_r_change = uieditfield(app.UIFigure, 'numeric');
            app.EditField_r_change.ValueChangedFcn = createCallbackFcn(app, @EditField_r_changeValueChanged, true);
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = ' θ(°)';
            app.EditField_theta_change = uieditfield(app.UIFigure, 'numeric');
            app.EditField_theta_change.ValueChangedFcn = createCallbackFcn(app, @EditField_theta_changeValueChanged, true);
            app.Slider_3 = uislider(app.UIFigure);
            app.Slider_3.Limits = [0 1];
            app.Slider_3.MajorTicks = [0 0.5 1];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_3.Value = 0.5;
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = ' h/L';
            app.EditField_h_change = uieditfield(app.UIFigure, 'numeric');
            app.EditField_h_change.ValueChangedFcn = createCallbackFcn(app, @EditField_h_changeValueChanged, true);
            app.EditField_h_change.Value = 0.5;
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '频谱与声音';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'point_2.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = page5_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end