classdef page_5_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Label_9                matlab.ui.control.Label
        Label_8                matlab.ui.control.Label
        Label_7                matlab.ui.control.Label
        Label_6                matlab.ui.control.Label
        Label_5                matlab.ui.control.Label
        Label_4                matlab.ui.control.Label
        Slider_2               matlab.ui.control.Slider
        Label_3                matlab.ui.control.Label
        EditField_xita_change  matlab.ui.control.NumericEditField
        Label_2                matlab.ui.control.Label
        EditField_r_change     matlab.ui.control.NumericEditField
        Label                  matlab.ui.control.Label
        Slider                 matlab.ui.control.Slider
        Button_6               matlab.ui.control.Button
        Button_5               matlab.ui.control.Button
        Button_4               matlab.ui.control.Button
        Button_3               matlab.ui.control.Button
        Button_2               matlab.ui.control.Button
        Button                 matlab.ui.control.Button
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function UIAxes2ButtonDown(app, event)
            
        end

        % Value changed function: EditField_r_change, EditField_xita_change
        function EditField_r_changeValueChanged(app, event)
            load('.\para.mat')
            r0=app.EditField_r_change.Value*a;
            app.Slider.Value=double(app.EditField_r_change.Value);
            xita0=app.EditField_xita_change.Value;
            x0=r0*cos(xita0/180*pi);
            y0=r0*sin(xita0/180*pi);
            hh=0:0.001:L;
            tt=0:pi/200:2*pi;
            xx=zeros(length(hh),length(tt));
            yy=zeros(length(hh),length(tt));
            zz=zeros(length(hh),length(tt));
            for ix =1:length(hh)
                xx(ix,:)=a.*cos(tt);
            end
            for ii =1:length(hh)
                zz(ii,:)=a.*sin(tt);
            end
            for iI =1:length(hh)
                yy(iI,:)=hh(iI);
            end
            drum_draw=surf(app.UIAxes2,xx,yy,zz);
            app.UIAxes2.NextPlot='add';
            set(drum_draw,'edgecolor','none');
            view(app.UIAxes2,180,-120);  
            axis(app.UIAxes2,[-1.1*a,1.1*a,-1.1*a,1.1*a,-1.1*a,1.1*a]);
            tthh=linspace(0,2*pi,100);
            xx=a.*cos(tthh);
            yy=zeros(size(xx));
            zz=a.*sin(tthh);
            fill3(app.UIAxes2,xx,yy,zz,[1,0.95,0.8]);
            app.UIAxes2.NextPlot='add';
            plot3(app.UIAxes2,0,0,0,'.','color','k','MarkerSize',10);
            plot3(app.UIAxes2,a0*a,0,0,'.','color','r','MarkerSize',15);
            plot3(app.UIAxes2,x0,0,y0,'.','color','b','MarkerSize',15);
            app.UIAxes2.NextPlot='replacechildren';

        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            load('.\para.mat')
            r0=double(floor(app.Slider.Value*100)/100);
            app.EditField_r_change.Value=r0;
            r0=app.EditField_r_change.Value*a;
            app.Slider.Value=double(app.EditField_r_change.Value);
            xita0=app.EditField_xita_change.Value;
            x0=r0*cos(xita0/180*pi);
            y0=r0*sin(xita0/180*pi);
            hh=0:0.001:L;
            tt=0:pi/200:2*pi;
            xx=zeros(length(hh),length(tt));
            yy=zeros(length(hh),length(tt));
            zz=zeros(length(hh),length(tt));
            for ix =1:length(hh)
                xx(ix,:)=a.*cos(tt);
            end
            for ii =1:length(hh)
                zz(ii,:)=a.*sin(tt);
            end
            for iI =1:length(hh)
                yy(iI,:)=hh(iI);
            end
            drum_draw=surf(app.UIAxes2,xx,yy,zz);
            app.UIAxes2.NextPlot='add';
            set(drum_draw,'edgecolor','none');
            view(app.UIAxes2,180,-120);  
            axis(app.UIAxes2,[-1.1*a,1.1*a,-1.1*a,1.1*a,-1.1*a,1.1*a]);
            tthh=linspace(0,2*pi,100);
            xx=a.*cos(tthh);
            yy=zeros(size(xx));
            zz=a.*sin(tthh);
            fill3(app.UIAxes2,xx,yy,zz,[1,0.95,0.8]);
            app.UIAxes2.NextPlot='add';
            plot3(app.UIAxes2,0,0,0,'.','color','k','MarkerSize',10);
            plot3(app.UIAxes2,a0*a,0,0,'.','color','r','MarkerSize',10);
            plot3(app.UIAxes2,x0,0,y0,'.','color','b','MarkerSize',10);
            app.UIAxes2.NextPlot='replacechildren';
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            load('.\para.mat')
            xita0= floor(app.Slider_2.Value*100)/100;
            app.EditField_xita_change.Value=xita0;
            r0=double(app.Slider.Value);
            app.EditField_r_change.Value=r0;
            r0=app.EditField_r_change.Value*a;
            app.Slider.Value=double(app.EditField_r_change.Value);
            xita0=app.EditField_xita_change.Value;
            x0=r0*cos(xita0/180*pi);
            y0=r0*sin(xita0/180*pi);
            hh=0:0.001:L;
            tt=0:pi/200:2*pi;
            xx=zeros(length(hh),length(tt));
            yy=zeros(length(hh),length(tt));
            zz=zeros(length(hh),length(tt));
            for ix =1:length(hh)
                xx(ix,:)=a.*cos(tt);
            end
            for ii =1:length(hh)
                zz(ii,:)=a.*sin(tt);
            end
            for iI =1:length(hh)
                yy(iI,:)=hh(iI);
            end
            drum_draw=surf(app.UIAxes2,xx,yy,zz);
            app.UIAxes2.NextPlot='add';
            set(drum_draw,'edgecolor','none');
            view(app.UIAxes2,180,-120);  
            axis(app.UIAxes2,[-1.1*a,1.1*a,-1.1*a,1.1*a,-1.1*a,1.1*a]);
            tthh=linspace(0,2*pi,100);
            xx=a.*cos(tthh);
            yy=zeros(size(xx));
            zz=a.*sin(tthh);
            fill3(app.UIAxes2,xx,yy,zz,[1,0.95,0.8]);
            app.UIAxes2.NextPlot='add';
            plot3(app.UIAxes2,0,0,0,'.','color','k','MarkerSize',10);
            plot3(app.UIAxes2,a0*a,0,0,'.','color','r','MarkerSize',10);
            plot3(app.UIAxes2,x0,0,y0,'.','color','b','MarkerSize',10);
            app.UIAxes2.NextPlot='replacechildren';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '频谱')
            xlabel(app.UIAxes, '时间(s)')
            ylabel(app.UIAxes, '振幅(A.U.)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 500 1000 1500 2000];
            app.UIAxes.XTickLabel = {'0'; '500'; '1000'; '1500'; '2000'};
            app.UIAxes.YTick = [-1 0 1];
            app.UIAxes.YTickLabel = {'-1.0'; '1'};
            app.UIAxes.ZTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ZTick = [];
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Text = ' 不同模式下的振动';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Text = ' 给定初始条件的振动';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.Text = '膜上某点的振动';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.Text = ' 参数设置';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.Text = ' 数据导出';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.Text = ' 腔内某点的振动';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 1];
            app.Slider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '观察点位置 r';
            app.EditField_r_change = uieditfield(app.UIFigure, 'numeric');
            app.EditField_r_change.ValueChangedFcn = createCallbackFcn(app, @EditField_r_changeValueChanged, true);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '观察点位置 θ';
            app.EditField_xita_change = uieditfield(app.UIFigure, 'numeric');
            app.EditField_xita_change.ValueChangedFcn = createCallbackFcn(app, @EditField_r_changeValueChanged, true);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '*a';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [0 180];
            app.Slider_2.MajorTicks = [0 45 90 135 180];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '°';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '°';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '°';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '°';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '°';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '°';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = page_5_0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end